classdef interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        frame_windowEditField           matlab.ui.control.NumericEditField
        framewindowEditFieldLabel       matlab.ui.control.Label
        load_multi_mcd_button           matlab.ui.control.Button
        PartIVspecialforyouLabel        matlab.ui.control.Label
        PartIyamltomcdLabel             matlab.ui.control.Label
        PartIImachinelabelLabel         matlab.ui.control.Label
        PartIIIplaythevideoLabel        matlab.ui.control.Label
        convert_yaml_to_mcdButton       matlab.ui.control.Button
        Xuan_JRLabel                    matlab.ui.control.Label
        ThanksforHengRunhuiPingQiLabel  matlab.ui.control.Label
        s2frameEditField                matlab.ui.control.NumericEditField
        frame1sEditFieldLabel           matlab.ui.control.Label
        unit2umEditField                matlab.ui.control.NumericEditField
        unitumEditFieldLabel            matlab.ui.control.Label
        pixel2umEditField               matlab.ui.control.NumericEditField
        pixelumEditFieldLabel           matlab.ui.control.Label
        folder_of_saved_filesEditField  matlab.ui.control.EditField
        folderofsavedfilesLabel         matlab.ui.control.Label
        load_mcd_and_use_machine_labelButton  matlab.ui.control.Button
        full_path_to_mcdEditField       matlab.ui.control.EditField
        fullpathtomcdEditFieldLabel     matlab.ui.control.Label
        play_videoButton                matlab.ui.control.Button
        full_path_to_the_playerEditField  matlab.ui.control.EditField
        fullpathtotheplayerEditFieldLabel  matlab.ui.control.Label
        full_path_to_the_videoEditField  matlab.ui.control.EditField
        fullpathtothevideoEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function my_add_path(app)
            cdpath = mfilename('fullpath');
            cdpath = fileparts(cdpath);
            cd(cdpath); % enter .m filepath
            addpath(genpath(cdpath)); % add path
        end

        function assign_value(app)
            global pixel2um unit2um s2frame frame_window
            pixel2um = app.pixel2umEditField.Value;
            unit2um = app.unit2umEditField.Value;
            s2frame = app.s2frameEditField.Value;
            frame_window = app.frame_windowEditField.Value;

            global label_number_outlier label_number_beyond_edge label_number_human_flip
            label_number_outlier = 100;
            label_number_beyond_edge = 101;
            label_number_human_flip = 102;

            global folder_of_saved_files
            folder_of_saved_files = app.folder_of_saved_filesEditField.Value;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % add path
            my_add_path(app);
            assign_value(app);
            
        end

        % Value changed function: full_path_to_the_videoEditField
        function full_path_to_the_videoEditFieldValueChanged(app, event)
%             videoPath = app.full_path_to_the_videoEditField.Value;            
        end

        % Value changed function: full_path_to_the_playerEditField
        function full_path_to_the_playerEditFieldValueChanged(app, event)
%             playerPath = app.full_path_to_the_playerEditField.Value;            
        end

        % Button pushed function: play_videoButton
        function play_videoButtonPushed(app, event)
            command = sprintf('"%s" "%s"', app.full_path_to_the_playerEditField.Value, app.full_path_to_the_videoEditField.Value);
            system(command);
        end

        % Button pushed function: load_mcd_and_use_machine_labelButton
        function load_mcd_and_use_machine_labelButtonPushed(app, event)
            
            clc;
            close all;
            disp('start, please wait...')
            full_path_to_mcd = app.full_path_to_mcdEditField.Value;
            assign_value(app);
            global folder_of_saved_files
            if ~isfolder(folder_of_saved_files)
                mkdir(folder_of_saved_files);
            end
            
            mcd = input_data(full_path_to_mcd);
            label_rearranged = machine_label(mcd);
            output_label(label_rearranged, folder_of_saved_files, 'machine_label.csv');
            output_figures(folder_of_saved_files)

        end

        % Value changed function: full_path_to_mcdEditField
        function full_path_to_mcdEditFieldValueChanged(app, event)
            
        end

        % Value changed function: folder_of_saved_filesEditField
        function folder_of_saved_filesEditFieldValueChanged(app, event)

        end

        % Value changed function: pixel2umEditField
        function pixel2umEditFieldValueChanged(app, event)
            
        end

        % Value changed function: unit2umEditField
        function unit2umEditFieldValueChanged(app, event)
         
        end

        % Value changed function: s2frameEditField
        function s2frameEditFieldValueChanged(app, event)
           
        end

        % Button pushed function: convert_yaml_to_mcdButton
        function convert_yaml_to_mcdButtonPushed(app, event)
            convert_multiple_Yaml_to_Mcd;
        end

        % Button pushed function: load_multi_mcd_button
        function load_multi_mcd_buttonPushed(app, event)
            assign_value(app);
            load_multi_mcd;
        end

        % Value changed function: frame_windowEditField
        function frame_windowEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fullpathtothevideoEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtothevideoEditFieldLabel.Text = 'full path to the video';
            app.full_path_to_the_videoEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_the_videoEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_the_videoEditFieldValueChanged, true);
            app.full_path_to_the_videoEditField.Value = 'F:\1_learning\research\Colbert\NC\NC20230312\w1\20230312_1921_w1_HUDS.avi';
            app.fullpathtotheplayerEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtotheplayerEditFieldLabel.Text = 'full path to the player';
            app.full_path_to_the_playerEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_the_playerEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_the_playerEditFieldValueChanged, true);
            app.full_path_to_the_playerEditField.Value = 'D:\Public\20220721_VirtualDub\VirtualDub-1.10.4-AMD64\Veedub64.exe';
            app.play_videoButton = uibutton(app.UIFigure, 'push');
            app.play_videoButton.ButtonPushedFcn = createCallbackFcn(app, @play_videoButtonPushed, true);
            app.play_videoButton.Text = 'play';
            app.fullpathtomcdEditFieldLabel = uilabel(app.UIFigure);
            app.fullpathtomcdEditFieldLabel.Text = 'full path to mcd';
            app.full_path_to_mcdEditField = uieditfield(app.UIFigure, 'text');
            app.full_path_to_mcdEditField.ValueChangedFcn = createCallbackFcn(app, @full_path_to_mcdEditFieldValueChanged, true);
            app.full_path_to_mcdEditField.Value = 'C:\Users\11097\SynologyDrive\Colbert有杂质数据\w1\mcd.mat';
            app.load_mcd_and_use_machine_labelButton = uibutton(app.UIFigure, 'push');
            app.load_mcd_and_use_machine_labelButton.ButtonPushedFcn = createCallbackFcn(app, @load_mcd_and_use_machine_labelButtonPushed, true);
            app.load_mcd_and_use_machine_labelButton.Text = 'load mcd and do machine label (take about 5s for 10000 frames)';
            app.folderofsavedfilesLabel = uilabel(app.UIFigure);
            app.folderofsavedfilesLabel.Text = 'folder of saved files';
            app.folder_of_saved_filesEditField = uieditfield(app.UIFigure, 'text');
            app.folder_of_saved_filesEditField.ValueChangedFcn = createCallbackFcn(app, @folder_of_saved_filesEditFieldValueChanged, true);
            app.folder_of_saved_filesEditField.Value = 'C:\Users\11097\SynologyDrive\Colbert有杂质数据\w1\machine_label';
            app.pixelumEditFieldLabel = uilabel(app.UIFigure);
            app.pixelumEditFieldLabel.Text = '1 pixel == ? um';
            app.pixel2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.pixel2umEditField.ValueChangedFcn = createCallbackFcn(app, @pixel2umEditFieldValueChanged, true);
            app.pixel2umEditField.Value = 1.6835;
            app.unitumEditFieldLabel = uilabel(app.UIFigure);
            app.unitumEditFieldLabel.Text = '1 unit == ? um';
            app.unit2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.unit2umEditField.ValueChangedFcn = createCallbackFcn(app, @unit2umEditFieldValueChanged, true);
            app.unit2umEditField.Value = 0.05;
            app.frame1sEditFieldLabel = uilabel(app.UIFigure);
            app.frame1sEditFieldLabel.Text = '? frame == 1 s';
            app.s2frameEditField = uieditfield(app.UIFigure, 'numeric');
            app.s2frameEditField.ValueChangedFcn = createCallbackFcn(app, @s2frameEditFieldValueChanged, true);
            app.s2frameEditField.Value = 66;
            app.ThanksforHengRunhuiPingQiLabel = uilabel(app.UIFigure);
            app.ThanksforHengRunhuiPingQiLabel.Text = 'Thanks for Heng, Runhui, Ping, Qi';
            app.Xuan_JRLabel = uilabel(app.UIFigure);
            app.Xuan_JRLabel.Text = 'Xuan_JR';
            app.convert_yaml_to_mcdButton = uibutton(app.UIFigure, 'push');
            app.convert_yaml_to_mcdButton.ButtonPushedFcn = createCallbackFcn(app, @convert_yaml_to_mcdButtonPushed, true);
            app.convert_yaml_to_mcdButton.Text = 'convert yaml to mcd (take about 1 minute for 10000 frames)';
            app.PartIIIplaythevideoLabel = uilabel(app.UIFigure);
            app.PartIIIplaythevideoLabel.Text = 'Part III: play the video';
            app.PartIImachinelabelLabel = uilabel(app.UIFigure);
            app.PartIImachinelabelLabel.Text = 'Part II: machine label';
            app.PartIyamltomcdLabel = uilabel(app.UIFigure);
            app.PartIyamltomcdLabel.Text = 'Part I: yaml to mcd';
            app.PartIVspecialforyouLabel = uilabel(app.UIFigure);
            app.PartIVspecialforyouLabel.Text = 'Part IV: special for you';
            app.load_multi_mcd_button = uibutton(app.UIFigure, 'push');
            app.load_multi_mcd_button.ButtonPushedFcn = createCallbackFcn(app, @load_multi_mcd_buttonPushed, true);
            app.load_multi_mcd_button.Text = 'load multi mcd and do machine label (take about 5s for 10000 frames)';
            app.framewindowEditFieldLabel = uilabel(app.UIFigure);
            app.framewindowEditFieldLabel.Text = 'frame window';
            app.frame_windowEditField = uieditfield(app.UIFigure, 'numeric');
            app.frame_windowEditField.ValueChangedFcn = createCallbackFcn(app, @frame_windowEditFieldValueChanged, true);
            app.frame_windowEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end